classdef EMCU < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        DCMotorButton               matlab.ui.control.Button
        StepperMotorButton          matlab.ui.control.Button
        ServoMotorButton            matlab.ui.control.Button
        ArduinoPanel                matlab.ui.container.Panel
        ConnectButton               matlab.ui.control.Button
        DisconnectButton            matlab.ui.control.Button
        DropDown                    matlab.ui.control.DropDown
        connectLamp                 matlab.ui.control.Lamp
        COMEditField1               matlab.ui.control.EditField
        KnobControlPanel            matlab.ui.container.Panel
        Knob                        matlab.ui.control.Knob
        PositionLabel               matlab.ui.control.Label
        PointsControlPanel          matlab.ui.container.Panel
        Label_first                 matlab.ui.control.Label
        EditField                   matlab.ui.control.NumericEditField
        EditField_2                 matlab.ui.control.NumericEditField
        Label_second                matlab.ui.control.Label
        EditField_3                 matlab.ui.control.NumericEditField
        Label_third                 matlab.ui.control.Label
        EditField_4                 matlab.ui.control.NumericEditField
        Label_fourth                matlab.ui.control.Label
        SecondsLabel                matlab.ui.control.Label
        GoButton                    matlab.ui.control.Button
        StopButton                  matlab.ui.control.Button
        DelayEditFieldLabel         matlab.ui.control.Label
        DelayEditField              matlab.ui.control.NumericEditField
        Switch                      matlab.ui.control.Switch
        BackButton                  matlab.ui.control.Button
        Switch_2                    matlab.ui.control.Switch
        AngleControlPanel           matlab.ui.container.Panel
        MaxLabel                    matlab.ui.control.Label
        MinLabel                    matlab.ui.control.Label
        SpeedControlSliderLabel     matlab.ui.control.Label
        SpeedControlSlider          matlab.ui.control.Slider
        SwitchStepper               matlab.ui.control.ToggleSwitch
        StepstomoveLabel            matlab.ui.control.Label
        stepsLabel                  matlab.ui.control.Label
        goAngle                     matlab.ui.control.Button
        AngletomoveEditFieldLabel   matlab.ui.control.Label
        AngletomoveEditField        matlab.ui.control.NumericEditField
        StepsControlPanel           matlab.ui.container.Panel
        CWButton                    matlab.ui.control.Button
        ACWButton                   matlab.ui.control.Button
        DriveModeDropDownLabel      matlab.ui.control.Label
        DriveModeDropDown           matlab.ui.control.DropDown
        ClearButton                 matlab.ui.control.Button
        StepsCW                     matlab.ui.control.Label
        AngleCW                     matlab.ui.control.Label
        StepsMovedLabel             matlab.ui.control.Label
        AngleMovedLabel             matlab.ui.control.Label
        StepsACW                    matlab.ui.control.Label
        AngleACW                    matlab.ui.control.Label
        ClockwiseLabel              matlab.ui.control.Label
        AnticlockwiseLabel          matlab.ui.control.Label
        SpeedDirectionControlPanel  matlab.ui.container.Panel
        Speedx1000rpmGaugeLabel     matlab.ui.control.Label
        Speedx1000rpmGauge          matlab.ui.control.SemicircularGauge
        Slider                      matlab.ui.control.Slider
        ClockwiseButton             matlab.ui.control.Button
        AntiClockwiseButton         matlab.ui.control.Button
        StopButton_2                matlab.ui.control.Button
        VoltageVoltsLabel           matlab.ui.control.Label
        ACMotorButton               matlab.ui.control.Button
        SpeedACPanel                matlab.ui.container.Panel
        StartButtonAC               matlab.ui.control.Button
        StopButtonAC                matlab.ui.control.Button
        UIAxes                      matlab.ui.control.UIAxes
        ButtonLogo                  matlab.ui.control.Button
        DCMotorLabel                matlab.ui.control.Label
        ServoMotorLabel             matlab.ui.control.Label
        ACMotorLabel                matlab.ui.control.Label
        StepperMotorLabel           matlab.ui.control.Label
    end

    
    properties (Access = private)
        
        UIAxes_2;
        UIAxes_3;
        dcPin1='D4';
        dcPin2='D7';
        dcPWM='D5';
        acPin='D11';
        srvoPin='D9';
        srvo1='D2';
        srvoPWM='D3';
        stprPin1='D6';
        stprPin2='D8';
        stprPin3='D12';
        stprPin4='D13';


    end
    
    
    methods (Access = public)
        
        function dcGo(app)
            global direction;
            global a;
            
            if direction==1
                writeDigitalPin(a,app.dcPin1,1);
                writeDigitalPin(a,app.dcPin2,0);
            elseif direction ==-1
                writeDigitalPin(a,app.dcPin1,0);
                writeDigitalPin(a,app.dcPin2,1);
            end
        end
        
        function dcStop(app)
            
            global a;
            writeDigitalPin(a,app.dcPin1,0);
            writeDigitalPin(a,app.dcPin2,0);
            
        end
        
        function ibraFullStep(app,A,B,C,D)
            global coilF;
            global a;
            delay=100;
            
            switch coilF
                case 1
                    writeDigitalPin(a,A,0);
                    writeDigitalPin(a,B,1);
                    writeDigitalPin(a,C,1);
                    writeDigitalPin(a,D,1);
                    java.lang.Thread.sleep(delay);
                case 2
                    
                    writeDigitalPin(a,A,1);
                    writeDigitalPin(a,B,0);
                    writeDigitalPin(a,C,1);
                    writeDigitalPin(a,D,1);
                    java.lang.Thread.sleep(delay);
                case 3
                    
                    writeDigitalPin(a,A,1);
                    writeDigitalPin(a,B,1);
                    writeDigitalPin(a,C,0);
                    writeDigitalPin(a,D,1);
                    java.lang.Thread.sleep(delay);
                otherwise
                    
                    writeDigitalPin(a,A,1);
                    writeDigitalPin(a,B,1);
                    writeDigitalPin(a,C,1);
                    writeDigitalPin(a,D,0);
                    java.lang.Thread.sleep(delay);
            end
            coilF=coilF+1;
            if coilF == 5
                coilF=1;
                
                
            end
        end


        function ibraHalfStep(app,A,B,C,D)
            
            global coilH;
            global a;
            delay=100;
            
            switch coilH
                case 1
                    
                    writeDigitalPin(a,A,0);
                    writeDigitalPin(a,B,1);
                    writeDigitalPin(a,C,1);
                    writeDigitalPin(a,D,1);
                    java.lang.Thread.sleep(delay);
                case 2
                    
                    writeDigitalPin(a,A,0);
                    writeDigitalPin(a,B,0);
                    writeDigitalPin(a,C,1);
                    writeDigitalPin(a,D,1);
                    java.lang.Thread.sleep(delay);
                case 3
                    
                    writeDigitalPin(a,A,1);
                    writeDigitalPin(a,B,0);
                    writeDigitalPin(a,C,1);
                    writeDigitalPin(a,D,1);
                    java.lang.Thread.sleep(delay);
                case 4
                    
                    writeDigitalPin(a,A,1);
                    writeDigitalPin(a,B,0);
                    writeDigitalPin(a,C,0);
                    writeDigitalPin(a,D,1);
                    java.lang.Thread.sleep(delay);
                case 5
                    writeDigitalPin(a,A,1);
                    writeDigitalPin(a,B,1);
                    writeDigitalPin(a,C,0);
                    writeDigitalPin(a,D,1);
                    java.lang.Thread.sleep(delay);
                case 6
                    
                    writeDigitalPin(a,A,1);
                    writeDigitalPin(a,B,1);
                    writeDigitalPin(a,C,0);
                    writeDigitalPin(a,D,0);
                    java.lang.Thread.sleep(delay);
                case 7
                    
                    writeDigitalPin(a,A,1);
                    writeDigitalPin(a,B,1);
                    writeDigitalPin(a,C,1);
                    writeDigitalPin(a,D,0);
                    java.lang.Thread.sleep(delay);
                otherwise
                    
                    writeDigitalPin(a,A,0);
                    writeDigitalPin(a,B,1);
                    writeDigitalPin(a,C,1);
                    writeDigitalPin(a,D,0);
                    java.lang.Thread.sleep(delay);
                    
                    coilH = coilH+1;
                    if coilH ==9
                        coilH =1;
                    end
            end
            
            
        end


        function ibraContStep(app,A,B,C,D,steps,delay)
            global a;
            
            for i =0:steps
                
                writeDigitalPin(a,A,0);
                writeDigitalPin(a,B,1);
                writeDigitalPin(a,C,1);
                writeDigitalPin(a,D,1);
                java.lang.Thread.sleep(delay);
                
                writeDigitalPin(a,A,1);
                writeDigitalPin(a,B,0);
                writeDigitalPin(a,C,1);
                writeDigitalPin(a,D,1);
                java.lang.Thread.sleep(delay);
                
                writeDigitalPin(a,A,1);
                writeDigitalPin(a,B,1);
                writeDigitalPin(a,C,0);
                writeDigitalPin(a,D,1);
                java.lang.Thread.sleep(delay);
                
                writeDigitalPin(a,A,1);
                writeDigitalPin(a,B,1);
                writeDigitalPin(a,C,1);
                writeDigitalPin(a,D,0);
                java.lang.Thread.sleep(delay);
            end
            writeDigitalPin(a,A,0);
            writeDigitalPin(a,B,0);
            writeDigitalPin(a,C,0);
            writeDigitalPin(a,D,0);
        end
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            logo = imread('RoboKIT.png');
            imshow(logo, 'parent', app.UIAxes);
            
        end

        % Button pushed function: DCMotorButton
        function DCMotorButtonPushed(app, event)
            %global dcm; global shield;
            global direction; global counter;
            counter=1;
            direction =1;
            
            app.DCMotorButton.Visible='off';
            app.StepperMotorButton.Visible='off';
            app.ServoMotorButton.Visible='off';
            app.UIAxes.Visible='off';
            app.ArduinoPanel.Visible='off';
            app.ACMotorButton.Visible='off';
            delete(app.UIAxes);
            
            app.SpeedDirectionControlPanel.Visible='on';
            app.BackButton.Visible='on';
            app.ButtonLogo.Visible='on';
            app.DCMotorLabel.Visible='on';
            
            
            % Create UIAxes_2
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, '')
            xlabel(app.UIAxes_2, 'Voltage (Volts)')
            ylabel(app.UIAxes_2, 'Speed (r.p.m.)')
            app.UIAxes_2.XColor = [1 1 1];
            app.UIAxes_2.YColor = [1 1 1];
            app.UIAxes_2.BackgroundColor = [0.15,0.15,0.15];
            app.UIAxes_2.Position = [426 75 375 325];
            
            %dcm = dcmotor(shield,2);
            
        end

        % Button pushed function: StepperMotorButton
        function StepperMotorButtonPushed(app, event)
            app.DCMotorButton.Visible='off';
            app.StepperMotorButton.Visible='off';
            app.ServoMotorButton.Visible='off';
            app.UIAxes.Visible='off';
            app.ArduinoPanel.Visible='off';
            app.ACMotorButton.Visible='off';
            delete(app.UIAxes);
            
            app.Switch_2.Visible='on';
            app.AngleControlPanel.Visible='on';
            app.StepsControlPanel.Visible='on';
            app.BackButton.Visible='on';
            app.ButtonLogo.Visible='on';
            app.StepperMotorLabel.Visible='on';
            
            global coilH; global coilF;
            coilH=1;
            coilF=1;
            
            global stepsCW;global angleCW;
            global stepsACW;global angleACW;
            stepsCW=0; angleCW=0;
            stepsACW=0; angleACW=0;


        end

        % Button pushed function: ServoMotorButton
        function ServoMotorButtonPushed(app, event)
            %global s; global shield;
            %s = servo(shield,1);
            global a;
            
            app.DCMotorButton.Visible='off';
            app.StepperMotorButton.Visible='off';
            app.ServoMotorButton.Visible='off';
            app.UIAxes.Visible='off';
            app.ArduinoPanel.Visible='off';
            app.ACMotorButton.Visible='off';
            app.UIAxes.Visible='off';
            delete(app.UIAxes);
            
            app.KnobControlPanel.Visible='on';
            app.PointsControlPanel.Visible='on';
            app.Switch.Visible='on';
            app.BackButton.Visible='on';
            app.ButtonLogo.Visible='on';
            app.ServoMotorLabel.Visible='on';
            
            %writeDigitalPin(a,app.srvo1,1);
            
            
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            global a;
            %global shield;
            global s;
            
            comVariable= app.COMEditField1.Value;
            boardVariable=app.DropDown.Value;
            try
                %a = arduino(comVariable,boardVariable,'Libraries','Adafruit/MotorShieldV2');
                a = arduino(comVariable,boardVariable,'Libraries','Servo');
                %shield = addon(a,'Adafruit\MotorshieldV2');
                
                configurePin(a,app.dcPin1, 'DigitalOutput');
                configurePin(a,app.dcPin2, 'DigitalOutput');
                configurePin(a,app.dcPWM, 'DigitalOutput');
                %configurePin(a,app.acPin,'Unset');
                
                %configurePin(a,app.srvoPin, 'DigitalOutput');
                
                configurePin(a,app.stprPin1, 'DigitalOutput');
                configurePin(a,app.stprPin2, 'DigitalOutput');
                configurePin(a,app.stprPin3, 'DigitalOutput');
                configurePin(a,app.stprPin4, 'DigitalOutput');
                configurePin(a,app.srvoPin, 'Servo');
                
                
                s= servo(a, app.srvoPin, 'MinPulseDuration', 700*10^-6, 'MaxPulseDuration', 2300*10^-6);
                writePWMVoltage(a,app.srvoPWM,4.3);
                app.connectLamp.Color='green';
                
            catch
                %f=uifigure;
                errordlg('Please make sure an arduino is connected to your PC and that you selected the correct board name and port number.'...
                    ,'Cannot connect to Arduino');
            end
            
        end

        % Button pushed function: DisconnectButton
        function DisconnectButtonPushed(app, event)
            %comVariable= app.COMEditField1.Value;
            
            %delete(instrfind({'Port'},{comVariable}));
            %clear
            %global a;
            %clearvars -global a;
            app.connectLamp.Color='black';
            clear all
            
            
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
            if value=="Knob Control"
                app.EditField.Enable='off';
                app.Label_first.Enable='off';
                app.EditField_2.Enable='off';
                app.Label_second.Enable='off';
                app.EditField_3.Enable='off';
                app.Label_third.Enable='off';
                app.EditField_4.Enable='off';
                app.Label_fourth.Enable='off';
                app.GoButton.Enable='off';
                app.StopButton.Enable='off';
                app.DelayEditField.Enable='off';
                app.SecondsLabel.Enable='off';
                app.DelayEditFieldLabel.Enable='off';
                
                
                app.PositionLabel.Enable='on';
                app.Knob.Enable='on';
                
                
            else
                app.EditField.Enable='on';
                app.Label_first.Enable='on';
                app.EditField_2.Enable='on';
                app.Label_second.Enable='on';
                app.EditField_3.Enable='on';
                app.Label_third.Enable='on';
                app.EditField_4.Enable='on';
                app.Label_fourth.Enable='on';
                app.GoButton.Enable='on';
                app.StopButton.Enable='on';
                app.DelayEditField.Enable='on';
                app.SecondsLabel.Enable='on';
                app.DelayEditFieldLabel.Enable='on';
                
                
                app.PositionLabel.Enable='off';
                app.Knob.Enable='off';
            end
        end

        % Value changing function: Knob
        function KnobValueChanging(app, event)
            global s;
            
            changingValue = event.Value;
            changingValue=floor(changingValue);
            changingValue=changingValue/180;
            try
                writePosition(s,changingValue);
            catch
                errordlg('Please make sure you are connected to the arduino. Go to the main menu and click "Connect" after selecting the right ardudino board name and port number'...
                    ,'Arduino is not connected');
            end
            
        end

        % Button pushed function: BackButton
        function BackButtonPushed(app, event)
            delete(app.UIAxes_2);
            delete(app.UIAxes_3);
            global a;
            
            %Main stuff
            app.DCMotorButton.Visible='on';
            app.ACMotorButton.Visible='on';
            app.StepperMotorButton.Visible='on';
            app.ServoMotorButton.Visible='on';
            app.ArduinoPanel.Visible='on';
            
            
            %writeDigitalPin(a,app.srvo1,0); %turn off servo
            
            
            % Create UIAxes
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.AmbientLightColor = [0.651 0.651 0.651];
            app.UIAxes.ClippingStyle = 'rectangle';
            app.UIAxes.XTickLabel = '';
            app.UIAxes.YTickLabel = '';
            app.UIAxes.Color = [0.6196 0.6196 0.6196];
            app.UIAxes.BackgroundColor = [0.5804 0.5804 0.5804];
            app.UIAxes.Position = [110 237 638 288];
            logo = imread('RoboKIT.png');
            imshow(logo, 'parent', app.UIAxes);
            
            %Servo Stuff
            app.KnobControlPanel.Visible='off';
            app.PointsControlPanel.Visible='off';
            app.Switch.Visible='off';
            app.BackButton.Visible='off';
            
            
            app.ButtonLogo.Visible='off';
            app.DCMotorLabel.Visible='off';
            app.ACMotorLabel.Visible='off';
            app.StepperMotorLabel.Visible='off';
            app.ServoMotorLabel.Visible='off';
            
            %Stepper stuff
            app.Switch_2.Visible='off';
            app.AngleControlPanel.Visible='off';
            app.StepsControlPanel.Visible='off';
            app.BackButton.Visible='off';
            
            %DC stuff
            app.SpeedDirectionControlPanel.Visible='off';
            
            %AC stuff
            app.SpeedACPanel.Visible='off';
            
        end

        % Value changed function: Switch_2
        function Switch_2ValueChanged(app, event)
            value = app.Switch_2.Value;
            if value == "Angle Control"
                app.CWButton.Enable='off';
                app.ACWButton.Enable='off';
                app.DriveModeDropDown.Enable='off';
                app.DriveModeDropDownLabel.Enable='off';
                app.ClearButton.Enable='off';
                app.StepsCW.Enable='off';
                app.AngleCW.Enable='off';
                app.StepsMovedLabel.Enable='off';
                app.AngleMovedLabel.Enable='off';
                app.StepsACW.Enable='off';
                app.AngleACW.Enable='off';
                app.ClockwiseLabel.Enable='off';
                app.AnticlockwiseLabel.Enable='off';
                
                app.MaxLabel.Enable='on';
                app.StepstomoveLabel.Enable='on';
                app.stepsLabel.Enable='on';
                app.MinLabel.Enable='on';
                app.goAngle.Enable='on';
                app.AngletomoveEditFieldLabel.Enable='on';
                app.AngletomoveEditField.Enable='on';
                app.SpeedControlSliderLabel.Enable='on';
                app.SpeedControlSlider.Enable='on';
                app.SwitchStepper.Enable='on';
                
            else
                app.CWButton.Enable='on';
                app.ACWButton.Enable='on';
                app.DriveModeDropDown.Enable='on';
                app.DriveModeDropDownLabel.Enable='on';
                app.ClearButton.Enable='on';
                app.StepsCW.Enable='on';
                app.AngleCW.Enable='on';
                app.StepsMovedLabel.Enable='on';
                app.AngleMovedLabel.Enable='on';
                app.StepsACW.Enable='on';
                app.AngleACW.Enable='on';
                app.ClockwiseLabel.Enable='on';
                app.AnticlockwiseLabel.Enable='on';
                
                app.MaxLabel.Enable='off';
                app.StepstomoveLabel.Enable='off';
                app.stepsLabel.Enable='off';
                app.MinLabel.Enable='off';
                app.goAngle.Enable='off';
                app.AngletomoveEditFieldLabel.Enable='off';
                app.AngletomoveEditField.Enable='off';
                app.SpeedControlSliderLabel.Enable='off';
                app.SpeedControlSlider.Enable='off';
                app.SwitchStepper.Enable='off';
                
            end
        end

        % Button pushed function: ClockwiseButton
        function ClockwiseButtonPushed(app, event)
            global direction;
            global speed;
            global voltage;
            global a;
            global counter;
            global speedVector;
            global voltageVector;
            
            
            voltage=app.Slider.Value;
            try
                writePWMVoltage(a,app.dcPWM,voltage);
                
                speed=voltage*4850/5; %needs equation
                app.Speedx1000rpmGauge.Value=speed;
                
                speedVector(counter)=speed;
                voltageVector(counter)=voltage*5/9;
                counter=counter+1;
                
                plot(app.UIAxes_2,voltageVector,speedVector)
                drawnow
                
                
                direction =1;
                dcGo(app);
            catch
                errordlg('Please make sure you are connected to the arduino. Go to the main menu and click "Connect" after selecting the right ardudino board name and port number'...
                    ,'Arduino is not connected');
            end
            
        end

        % Button pushed function: AntiClockwiseButton
        function AntiClockwiseButtonPushed(app, event)
            global direction;
            global speed;
            global voltage;
            global counter;
            global a;
            global speedVector;
            global voltageVector;
            
            voltage=app.Slider.Value;
            try
                writePWMVoltage(a,app.dcPWM,voltage);
                
                speed=voltage*4850/5; %needs equation
                app.Speedx1000rpmGauge.Value=speed;
                
                speedVector(counter)=speed;
                voltageVector(counter)=voltage;
                counter=counter+1;
                
                plot(app.UIAxes_2,voltageVector,speedVector)
                drawnow
                
                direction =-1;
                dcGo(app);
            catch
                errordlg('Please make sure you are connected to the arduino. Go to the main menu and click "Connect" after selecting the right ardudino board name and port number'...
                    ,'Arduino is not connected');
            end
            
            
        end

        % Button pushed function: StopButton_2
        function StopButton_2Pushed(app, event)
            try
                dcStop(app);
            catch
                errordlg('Please make sure you are connected to the arduino. Go to the main menu and click "Connect" after selecting the right ardudino board name and port number'...
                    ,'Arduino is not connected');
            end
            
        end

        % Value changing function: Slider
        function SliderValueChanging(app, event)
            %global dcm;
            %global direction;
            %speed = event.Value;
            %speed=speed*direction;
            %dcm.Speed=speed;
            global a;
            try
                speed = event.Value;
                writePWMVoltage(a,app.dcPWM,speed);
                app.Speedx1000rpmGauge.Value=speed; %needs equation
            catch
                errordlg('Please make sure you are connected to the arduino. Go to the main menu and click "Connect" after selecting the right ardudino board name and port number'...
                    ,'Arduino is not connected');
            end
            
        end

        % Button pushed function: ACMotorButton
        function ACMotorButtonPushed(app, event)
            global fan1; global fan2;
            app.DCMotorButton.Visible='off';
            app.StepperMotorButton.Visible='off';
            app.ServoMotorButton.Visible='off';
            app.UIAxes.Visible='off';
            app.ArduinoPanel.Visible='off';
            app.ACMotorButton.Visible='off';
            app.UIAxes.Visible='off';
            delete(app.UIAxes);
            
            
            app.BackButton.Visible='on';
            app.SpeedACPanel.Visible='on';
            app.ButtonLogo.Visible='on';
            app.ACMotorLabel.Visible='on';
            
            % Create UIAxes_3
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, '')
            app.UIAxes_3.XColor = [1 1 1];
            app.UIAxes_3.YColor = [1 1 1];
            app.UIAxes_3.BackgroundColor = [0.59,0.59,0.59];
            app.UIAxes_3.Position = [426 75 375 325];
            fan1=imread('fan1.png');
            fan2=imread('fan2.png');
            imshow(fan1, 'parent', app.UIAxes_3);
            drawnow
            
        end

        % Button pushed function: GoButton
        function GoButtonPushed(app, event)
            global go;
            global s;
            go=true;
            
            angle1=app.EditField.Value/180;
            angle2=app.EditField_2.Value/180;
            angle3=app.EditField_3.Value/180;
            angle4=app.EditField_4.Value/180;
            delay=app.DelayEditField.Value;
            if delay<=0
                    errordlg('Please select a delay value more than zero '...
                    ,'Delay cannot be negative ');
            end
            
            if angle1<0 || angle2<0 || angle3<0 || angle4<0 ||angle1>180 || angle2>180 || angle3>180 || angle4>180
                errordlg('Please select angles between 0 and 180 degrees.','Invali angle values');
            else
                try
                    while go==true
                        
                        writePosition(s,angle1);
                        pause(delay);
                        if go==false
                            break;
                        end
                        writePosition(s,angle2);
                        pause(delay);
                        if go==false
                            break;
                        end
                        writePosition(s,angle3);
                        pause(delay);
                        if go==false
                            break;
                        end
                        writePosition(s,angle4);
                        pause(delay);
                    end
                catch

                    errordlg('Please make sure you are connected to the arduino. Go to the main menu and click "Connect" after selecting the right ardudino board name and port number'...
                        ,'Arduino is not connected');
                    
                end
            end
            
            
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            global go;
            go=false;
        end

        % Button pushed function: goAngle
        function goAngleButtonPushed(app, event)
            
            delay=app.SpeedControlSlider.Value; %needs review
            delay=delay*10;
            direction = app.SwitchStepper.Value;
            
            angle=app.AngletomoveEditField.Value;
            if angle<0

                errordlg('Please select a positve angle. If you want the motor to move in the oppasite direction, use the switch.'...
                    ,'Angle cannot be negative');
            end
            
            steps=round(angle*360/200);
            app.stepsLabel.Text=num2str(steps);
            drawnow
            try
                if direction=="Clockwise"
                    ibraContStep(app,app.stprPin1,app.stprPin2,app.stprPin3,app.stprPin4,steps,delay);
                else
                    ibraContStep(app,app.stprPin4,app.stprPin3,app.stprPin2,app.stprPin1,steps,delay);
                end
            catch
                errordlg('Please make sure you are connected to the arduino. Go to the main menu and click "Connect" after selecting the right ardudino board name and port number'...
                    ,'Arduino is not connected');
            end


        end

        % Button pushed function: ACWButton
        function ACWButtonPushed(app, event)
            global stepsACW;
            global angleACW;
            global a;
            
            mode=app.DriveModeDropDown.Value;
            try
                if mode=="Full Step"
                    ibraFullStep(app,app.stprPin4,app.stprPin3,app.stprPin2,app.stprPin1);
                    
                    stepsACW=stepsACW+1;
                    
                else
                    ibraHalfStep(app,app.stprPin4,app.stprPin3,app.stprPin2,app.stprPin1);
                    stepsACW=stepsACW+0.5;
                    
                end
                writeDigitalPin(a,app.stprPin1,0);
                writeDigitalPin(a,app.stprPin2,0);
                writeDigitalPin(a,app.stprPin3,0);
                writeDigitalPin(a,app.stprPin4,0);
                
                angleACW=stepsACW*200/360;
                
                app.StepsACW.Text=num2str(stepsACW);
                app.AngleACW.Text=num2str(angleACW);
                drawnow
            catch
                errordlg('Please make sure you are connected to the arduino. Go to the main menu and click "Connect" after selecting the right ardudino board name and port number'...
                    ,'Arduino is not connected');
            end
            
            
        end

        % Button pushed function: CWButton
        function CWButtonPushed(app, event)
            global stepsCW;
            global angleCW;
            global a;
            
            mode=app.DriveModeDropDown.Value;
            try
                if mode=="Full Step"
                    ibraFullStep(app,app.stprPin1,app.stprPin2,app.stprPin3,app.stprPin4);
                    stepsCW=stepsCW+1;
                    
                else
                    ibraHalfStep(app,app.stprPin1,app.stprPin2,app.stprPin3,app.stprPin4);
                    stepsCW=stepsCW+0.5;
                    
                end
                angleCW=stepsCW*200/360;
                writeDigitalPin(a,app.stprPin1,0);
                writeDigitalPin(a,app.stprPin2,0);
                writeDigitalPin(a,app.stprPin3,0);
                writeDigitalPin(a,app.stprPin4,0);
                
                app.StepsCW.Text=num2str(stepsCW);
                app.AngleCW.Text=num2str(angleCW);
                drawnow
            catch
                errordlg('Please make sure you are connected to the arduino. Go to the main menu and click "Connect" after selecting the right ardudino board name and port number'...
                    ,'Arduino is not connected');
            end
            
            
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            global stepsCW;
            global angleCW;
            global stepsACW;
            global angleACW;
            stepsACW=0;
            stepsCW=0;
            angleACW=0;
            angleCW=0;
            
            app.StepsCW.Text="";
            app.AngleCW.Text="";
            app.StepsACW.Text="";
            app.AngleACW.Text="";
            
        end

        % Button pushed function: StartButtonAC
        function StartButtonACPushed(app, event)
            global a; global fan1; global fan2;
            global fanOn;
            fanOn=true;
            
            try
                configurePin(a,app.acPin,'DigitalOutput');
                
                while fanOn==true
                    imshow(fan1, 'parent', app.UIAxes_3);
                    drawnow
                    pause(0.25);
                    imshow(fan2, 'parent', app.UIAxes_3);
                    drawnow
                    pause(0.25);
                end
            catch
                errordlg('Please make sure you are connected to the arduino. Go to the main menu and click "Connect" after selecting the right ardudino board name and port number'...
                    ,'Arduino is not connected');
            end


        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app);
        end

        % Button pushed function: StopButtonAC
        function StopButtonACPushed(app, event)
            global a; global fanOn
            fanOn=false;
             try
                %writeDigitalPin(a,app.acPin,1);
                configurePin(a,app.acPin,'Unset');
                
             catch
                errordlg('Please make sure you are connected to the arduino. Go to the main menu and click "Connect" after selecting the right ardudino board name and port number'...
                    ,'Arduino is not connected');
            end
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.DCMotorButton = uibutton(app.UIFigure, 'push');
            app.DCMotorButton.ButtonPushedFcn = createCallbackFcn(app, @DCMotorButtonPushed, true);
            app.DCMotorButton.Text = {'DC'; 'Motor'};
            app.StepperMotorButton = uibutton(app.UIFigure, 'push');
            app.StepperMotorButton.ButtonPushedFcn = createCallbackFcn(app, @StepperMotorButtonPushed, true);
            app.StepperMotorButton.Text = {'Stepper'; 'Motor'};
            app.ServoMotorButton = uibutton(app.UIFigure, 'push');
            app.ServoMotorButton.ButtonPushedFcn = createCallbackFcn(app, @ServoMotorButtonPushed, true);
            app.ServoMotorButton.Text = {'Servo'; 'Motor'};
            app.ArduinoPanel = uipanel(app.UIFigure);
            app.ArduinoPanel.Title = 'Arduino';
            app.ConnectButton = uibutton(app.ArduinoPanel, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.DisconnectButton = uibutton(app.ArduinoPanel, 'push');
            app.DisconnectButton.ButtonPushedFcn = createCallbackFcn(app, @DisconnectButtonPushed, true);
            app.DisconnectButton.Text = 'Disconnect';
            app.DropDown = uidropdown(app.ArduinoPanel);
            app.DropDown.Items = {'Uno', 'Mega', 'Due'};
            app.DropDown.Value = 'Uno';
            app.connectLamp = uilamp(app.ArduinoPanel);
            app.COMEditField1 = uieditfield(app.ArduinoPanel, 'text');
            app.COMEditField1.Value = 'COM';
            app.KnobControlPanel = uipanel(app.UIFigure);
            app.KnobControlPanel.Title = 'Knob Control';
            app.KnobControlPanel.Visible = 'off';
            app.Knob = uiknob(app.KnobControlPanel, 'continuous');
            app.Knob.Limits = [0 180];
            app.Knob.MajorTicks = [0 30 60 90 120 150 180];
            app.Knob.ValueChangingFcn = createCallbackFcn(app, @KnobValueChanging, true);
            app.Knob.MinorTicks = [10 20 40 50 70 80 100 110 130 140 160 170];
            app.PositionLabel = uilabel(app.KnobControlPanel);
            app.PositionLabel.Text = 'Position';
            app.PointsControlPanel = uipanel(app.UIFigure);
            app.PointsControlPanel.Title = 'Points Control';
            app.PointsControlPanel.Visible = 'off';
            app.Label_first = uilabel(app.PointsControlPanel);
            app.Label_first.Enable = 'off';
            app.Label_first.Text = '1';
            app.EditField = uieditfield(app.PointsControlPanel, 'numeric');
            app.EditField.Enable = 'off';
            app.EditField_2 = uieditfield(app.PointsControlPanel, 'numeric');
            app.EditField_2.Enable = 'off';
            app.Label_second = uilabel(app.PointsControlPanel);
            app.Label_second.Enable = 'off';
            app.Label_second.Text = '2';
            app.EditField_3 = uieditfield(app.PointsControlPanel, 'numeric');
            app.EditField_3.Enable = 'off';
            app.Label_third = uilabel(app.PointsControlPanel);
            app.Label_third.Enable = 'off';
            app.Label_third.Text = '3';
            app.EditField_4 = uieditfield(app.PointsControlPanel, 'numeric');
            app.EditField_4.Enable = 'off';
            app.Label_fourth = uilabel(app.PointsControlPanel);
            app.Label_fourth.Enable = 'off';
            app.Label_fourth.Text = '4';
            app.SecondsLabel = uilabel(app.PointsControlPanel);
            app.SecondsLabel.Enable = 'off';
            app.SecondsLabel.Text = 'Seconds';
            app.GoButton = uibutton(app.PointsControlPanel, 'push');
            app.GoButton.ButtonPushedFcn = createCallbackFcn(app, @GoButtonPushed, true);
            app.GoButton.Enable = 'off';
            app.GoButton.Text = 'Go';
            app.StopButton = uibutton(app.PointsControlPanel, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Enable = 'off';
            app.StopButton.Text = 'Stop';
            app.DelayEditFieldLabel = uilabel(app.PointsControlPanel);
            app.DelayEditFieldLabel.Enable = 'off';
            app.DelayEditFieldLabel.Text = 'Delay:';
            app.DelayEditField = uieditfield(app.PointsControlPanel, 'numeric');
            app.DelayEditField.Enable = 'off';
            app.Switch = uiswitch(app.UIFigure, 'slider');
            app.Switch.Items = {'Knob Control', 'Points Control'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Visible = 'off';
            app.Switch.Value = 'Knob Control';
            app.BackButton = uibutton(app.UIFigure, 'push');
            app.BackButton.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.BackButton.Icon = 'back.png';
            app.BackButton.Visible = 'off';
            app.BackButton.Text = '';
            app.Switch_2 = uiswitch(app.UIFigure, 'slider');
            app.Switch_2.Items = {'Angle Control', 'Steps Control'};
            app.Switch_2.ValueChangedFcn = createCallbackFcn(app, @Switch_2ValueChanged, true);
            app.Switch_2.Visible = 'off';
            app.Switch_2.Value = 'Angle Control';
            app.AngleControlPanel = uipanel(app.UIFigure);
            app.AngleControlPanel.Title = 'Angle Control';
            app.AngleControlPanel.Visible = 'off';
            app.MaxLabel = uilabel(app.AngleControlPanel);
            app.MaxLabel.Text = 'Max';
            app.MinLabel = uilabel(app.AngleControlPanel);
            app.MinLabel.Text = 'Min';
            app.SpeedControlSliderLabel = uilabel(app.AngleControlPanel);
            app.SpeedControlSliderLabel.Text = {'Speed'; 'Control'};
            app.SpeedControlSlider = uislider(app.AngleControlPanel);
            app.SpeedControlSlider.Limits = [0.1 3];
            app.SpeedControlSlider.MajorTicks = [];
            app.SpeedControlSlider.MinorTicks = [];
            app.SpeedControlSlider.Value = 0.1;
            app.SwitchStepper = uiswitch(app.AngleControlPanel, 'toggle');
            app.SwitchStepper.Items = {'Anti-clockwise', 'Clockwise'};
            app.SwitchStepper.Value = 'Clockwise';
            app.StepstomoveLabel = uilabel(app.AngleControlPanel);
            app.StepstomoveLabel.Text = 'Steps to move:';
            app.stepsLabel = uilabel(app.AngleControlPanel);
            app.stepsLabel.Text = '';
            app.goAngle = uibutton(app.AngleControlPanel, 'push');
            app.goAngle.ButtonPushedFcn = createCallbackFcn(app, @goAngleButtonPushed, true);
            app.goAngle.Text = 'Go';
            app.AngletomoveEditFieldLabel = uilabel(app.AngleControlPanel);
            app.AngletomoveEditFieldLabel.Text = 'Angle to move:';
            app.AngletomoveEditField = uieditfield(app.AngleControlPanel, 'numeric');
            app.StepsControlPanel = uipanel(app.UIFigure);
            app.StepsControlPanel.Title = 'Steps Control';
            app.StepsControlPanel.Visible = 'off';
            app.CWButton = uibutton(app.StepsControlPanel, 'push');
            app.CWButton.ButtonPushedFcn = createCallbackFcn(app, @CWButtonPushed, true);
            app.CWButton.Icon = 'clockwise.png';
            app.CWButton.Enable = 'off';
            app.CWButton.Text = '';
            app.ACWButton = uibutton(app.StepsControlPanel, 'push');
            app.ACWButton.ButtonPushedFcn = createCallbackFcn(app, @ACWButtonPushed, true);
            app.ACWButton.Icon = 'anticlockwise.png';
            app.ACWButton.Enable = 'off';
            app.ACWButton.Text = '';
            app.DriveModeDropDownLabel = uilabel(app.StepsControlPanel);
            app.DriveModeDropDownLabel.Enable = 'off';
            app.DriveModeDropDownLabel.Text = 'Drive Mode';
            app.DriveModeDropDown = uidropdown(app.StepsControlPanel);
            app.DriveModeDropDown.Items = {'Full Step', 'Half Step'};
            app.DriveModeDropDown.Enable = 'off';
            app.DriveModeDropDown.Value = 'Full Step';
            app.ClearButton = uibutton(app.StepsControlPanel, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Enable = 'off';
            app.ClearButton.Text = 'Clear';
            app.StepsCW = uilabel(app.StepsControlPanel);
            app.StepsCW.Enable = 'off';
            app.StepsCW.Text = '';
            app.AngleCW = uilabel(app.StepsControlPanel);
            app.AngleCW.Enable = 'off';
            app.AngleCW.Text = '';
            app.StepsMovedLabel = uilabel(app.StepsControlPanel);
            app.StepsMovedLabel.Enable = 'off';
            app.StepsMovedLabel.Text = 'Steps Moved:';
            app.AngleMovedLabel = uilabel(app.StepsControlPanel);
            app.AngleMovedLabel.Enable = 'off';
            app.AngleMovedLabel.Text = 'Angle Moved:';
            app.StepsACW = uilabel(app.StepsControlPanel);
            app.StepsACW.Enable = 'off';
            app.StepsACW.Text = '';
            app.AngleACW = uilabel(app.StepsControlPanel);
            app.AngleACW.Enable = 'off';
            app.AngleACW.Text = '';
            app.ClockwiseLabel = uilabel(app.StepsControlPanel);
            app.ClockwiseLabel.Enable = 'off';
            app.ClockwiseLabel.Text = 'Clockwise';
            app.AnticlockwiseLabel = uilabel(app.StepsControlPanel);
            app.AnticlockwiseLabel.Enable = 'off';
            app.AnticlockwiseLabel.Text = 'Anti-clockwise';
            app.SpeedDirectionControlPanel = uipanel(app.UIFigure);
            app.SpeedDirectionControlPanel.Title = 'Speed/Direction Control';
            app.SpeedDirectionControlPanel.Visible = 'off';
            app.Speedx1000rpmGaugeLabel = uilabel(app.SpeedDirectionControlPanel);
            app.Speedx1000rpmGaugeLabel.Text = 'Speed (x1000 r.p.m.)';
            app.Speedx1000rpmGauge = uigauge(app.SpeedDirectionControlPanel, 'semicircular');
            app.Speedx1000rpmGauge.Limits = [3000 5000];
            app.Speedx1000rpmGauge.MajorTicks = [3000 3200 3400 3600 3800 4000 4200 4400 4600 4800 5000];
            app.Speedx1000rpmGauge.MajorTickLabels = {'3', '3.2', '3.4', '3.6', '3.8', '4', '4.2', '4.4', '4.6', '4.8', '5'};
            app.Slider = uislider(app.SpeedDirectionControlPanel);
            app.Slider.Limits = [3.5 5];
            app.Slider.MajorTicks = [3.5 4 4.5 5];
            app.Slider.MajorTickLabels = {'6', '7', '8', '9'};
            app.Slider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging, true);
            app.Slider.MinorTicks = [];
            app.Slider.Value = 3.5;
            app.ClockwiseButton = uibutton(app.SpeedDirectionControlPanel, 'push');
            app.ClockwiseButton.ButtonPushedFcn = createCallbackFcn(app, @ClockwiseButtonPushed, true);
            app.ClockwiseButton.Text = 'Clockwise';
            app.AntiClockwiseButton = uibutton(app.SpeedDirectionControlPanel, 'push');
            app.AntiClockwiseButton.ButtonPushedFcn = createCallbackFcn(app, @AntiClockwiseButtonPushed, true);
            app.AntiClockwiseButton.Text = 'Anti-Clockwise';
            app.StopButton_2 = uibutton(app.SpeedDirectionControlPanel, 'push');
            app.StopButton_2.ButtonPushedFcn = createCallbackFcn(app, @StopButton_2Pushed, true);
            app.StopButton_2.Text = 'Stop';
            app.VoltageVoltsLabel = uilabel(app.SpeedDirectionControlPanel);
            app.VoltageVoltsLabel.Text = {'Voltage:'; '(Volts)'};
            app.ACMotorButton = uibutton(app.UIFigure, 'push');
            app.ACMotorButton.ButtonPushedFcn = createCallbackFcn(app, @ACMotorButtonPushed, true);
            app.ACMotorButton.Text = {'AC'; 'Motor'};
            app.SpeedACPanel = uipanel(app.UIFigure);
            app.SpeedACPanel.Title = 'Speed Control';
            app.SpeedACPanel.Visible = 'off';
            app.StartButtonAC = uibutton(app.SpeedACPanel, 'push');
            app.StartButtonAC.ButtonPushedFcn = createCallbackFcn(app, @StartButtonACPushed, true);
            app.StartButtonAC.Text = 'Start';
            app.StopButtonAC = uibutton(app.SpeedACPanel, 'push');
            app.StopButtonAC.ButtonPushedFcn = createCallbackFcn(app, @StopButtonACPushed, true);
            app.StopButtonAC.Text = 'Stop';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.XTickLabel = '';
            app.UIAxes.YTickLabel = '';
            app.ButtonLogo = uibutton(app.UIFigure, 'push');
            app.ButtonLogo.Icon = 'icon.png';
            app.ButtonLogo.Enable = 'off';
            app.ButtonLogo.Visible = 'off';
            app.ButtonLogo.Text = '';
            app.DCMotorLabel = uilabel(app.UIFigure);
            app.DCMotorLabel.Visible = 'off';
            app.DCMotorLabel.Text = 'DC Motor';
            app.ServoMotorLabel = uilabel(app.UIFigure);
            app.ServoMotorLabel.Visible = 'off';
            app.ServoMotorLabel.Text = 'Servo Motor';
            app.ACMotorLabel = uilabel(app.UIFigure);
            app.ACMotorLabel.Visible = 'off';
            app.ACMotorLabel.Text = 'AC Motor';
            app.StepperMotorLabel = uilabel(app.UIFigure);
            app.StepperMotorLabel.Visible = 'off';
            app.StepperMotorLabel.Text = 'Stepper Motor';
        end
    end

    methods (Access = public)

        % Construct app
        function app = EMCU

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end